classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        BtnCW        matlab.ui.control.StateButton
        BtnCCW       matlab.ui.control.StateButton
        BtnCamera    matlab.ui.control.Button
        BtnStream    matlab.ui.control.Button
        BtnBwdLeft   matlab.ui.control.StateButton
        BtnFwdLeft   matlab.ui.control.StateButton
        BtnBwdRight  matlab.ui.control.StateButton
        BtnFwdRight  matlab.ui.control.StateButton
        BtnLeft      matlab.ui.control.StateButton
        BtnBackward  matlab.ui.control.StateButton
        BtnRight     matlab.ui.control.StateButton
        BtnForward   matlab.ui.control.StateButton
        SldSpeed     matlab.ui.control.Slider
        SPEEDLabel   matlab.ui.control.Label
        BtnSimulate  matlab.ui.control.Button
        BtnConnect   matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties(Constant)
        %DH Parameters [theta, d, a, alpha], these parameters have been
        %extracted from CoppeliaSim module.
        DH_params = [
            0, 0.1012, 0.0330, 90;    % Joint 0 -> Joint 1
            0, 0.0000, 0.1550, 0;     % Joint 1 -> Joint 2
            0, 0.0000, 0.1348, 0;     % Joint 2 -> Joint 3
            0, 0.0190, 0.0005, -90;   % Joint 3 -> Joint 4
            0, 0.0973, 0.0001, -90    % Joint 4 -> End-effector
            ];

    end
    properties (Access = private)
        %Constants
        structJointTypes = struct( ...
        'REVOLUTE', 1, ...
        'PRISMATIC', 2 ...
    );


        connection % Instance of RobotConnection
        joints %Array of Joint objects
        baseControl %Base control object
        visionSensor %Vision Sensor object
        wheelsSpeed = 1.0; 
        timer_sendPos %clock to call api for joint positions
    end
    
    methods (Access = private)
        
        function retrieveAndInitializeJoints(app)
                        
            % Retrieve wheel handles from CoppeliaSim (use correct object names)
            [handleWheelFR, ~] = app.connection.getHandle('rollingJoint_fr');
            [handleWheelFL, ~] = app.connection.getHandle('rollingJoint_fl');
            [handleWheelRR, ~] = app.connection.getHandle('rollingJoint_rr');
            [handleWheelRL, ~] = app.connection.getHandle('rollingJoint_rl');
            % Store the handles in an array to pass to BaseControl
            wheelHandles = [handleWheelFR, handleWheelFL, handleWheelRR, handleWheelRL];
            % Initialize the BaseControl object
            app.baseControl = BaseControl(wheelHandles);
           
            % %Initialize the VisionSensor
             [visionHandle, ~] = app.connection.getHandle('/youBot/rgb');
             app.visionSensor = VisionSensor(visionHandle);
            

        end


        function disableUIControls(app)
           
            % Disable speed slider
            app.SldSpeed.Enable = 'off';

            % Disable base control buttons
            app.BtnFwdLeft.Enable = 'off';
            app.BtnBwdLeft.Enable = 'off';
            app.BtnFwdRight.Enable = 'off';
            app.BtnBwdRight.Enable = 'off';
            app.BtnForward.Enable = 'off';
            app.BtnBackward.Enable = 'off';
            app.BtnLeft.Enable = 'off';
            app.BtnRight.Enable = 'off';
            app.BtnCW.Enable = "off";
            app.BtnCCW.Enable = "off";
            app.BtnSimulate.Enable = "off";

            % Disable camera buttons
            app.BtnStream.Enable = 'off';
            app.BtnCamera.Enable = 'off';
                       
        end

        function enableUIControls(app)
            
            % Enable speed slider
            app.SldSpeed.Enable = 'on';

            % Enable base control buttons
            app.BtnFwdLeft.Enable = 'on';
            app.BtnBwdLeft.Enable = 'on';
            app.BtnFwdRight.Enable = 'on';
            app.BtnBwdRight.Enable = 'on';
            app.BtnForward.Enable = 'on';
            app.BtnBackward.Enable = 'on';
            app.BtnLeft.Enable = 'on';
            app.BtnRight.Enable = 'on';
            app.BtnCW.Enable = "on";
            app.BtnCCW.Enable = "on";
            app.BtnSimulate.Enable = "on";

            % Enable camera buttons
            app.BtnStream.Enable = 'on';
            app.BtnCamera.Enable = 'on';
        end

        function setupSliders(app)

            %Set limits for the speed slider 
            app.SldSpeed.Limits = [1,100];
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize RobotConnection
            app.connection = RobotConnection();
            app.BtnConnect.Text = 'Connect';
            app.BtnConnect.Icon = 'icons/Connect.png';
        end

        % Button pushed function: BtnConnect
        function BtnConnectButtonPushed(app, event)
            % Check the current state of the connection
            if app.connection.clientID == -1
                % Attempt to connect
                success = app.connection.connect();
                if success
                    % Retrieve and initialize joint handles
                    app.retrieveAndInitializeJoints();

                    % % Setup sliders dynamically
                    app.setupSliders();

                    % Disable UI controls after successful connection
                    app.disableUIControls();

                    % Change button icon and text to indicate "Disconnect"
                    app.BtnConnect.Text = 'Disconnect';
                    app.BtnConnect.Icon = 'icons/Disconnect.png';  % Assuming you have an icon file for disconnect

                    %enable Simulation Button
                    app.BtnSimulate.Enable = "on";
                    app.BtnSimulate.Icon = "icons/Start.png";
                end
            else
                % If already connected, disconnect
                app.connection.disconnect();
                % Disable UI controls after successful connection
                    app.disableUIControls();

                % Change button icon and text to indicate "Connect"
                app.BtnConnect.Text = 'Connect';
                app.BtnConnect.Icon = 'icons/Connect.png';  % Assuming you have an icon file for connect
            end
        end

        % Button pushed function: BtnSimulate
        function BtnSimulateButtonPushed(app, event)
            % Check if the client is connected
            if app.connection.clientID > -1
                % Determine if the simulation is currently running
                if ~app.connection.isSimulationRunning
                    %Must get current position and set joint pos to current
                    %position, coppeliasim retains last commanded values
                    %and on simulation start it moves the arm on the last
                    %commanded pos causing unwanted movement at the
                    %simulation beginning. 
                    for i = 1:length(app.joints)
                        % Get current position
                        app.joints{i}.getCurrentPosition(app.connection.sim, app.connection.clientID);

                        % Set target position to the current position (to prevent movement)
                        app.joints{i}.setPosition(app.connection.sim, app.connection.clientID, app.joints{i}.currentPos);
                    end
                    
                    % Start the simulation
                    app.connection.startSimulation();

                    % Check if the simulation started successfully
                    if app.connection.isSimulationRunning
                        % Enable UI controls
                        app.enableUIControls();
                        
                        % Update button icon to indicate "Stop Simulation"
                        app.BtnSimulate.Icon = 'icons/Stop.png';  % stop simulation icon
                    end
                else
                    % Stop the simulation
                    app.connection.stopSimulation();
                    
                    
                    % Check if the simulation stopped successfully
                    if ~app.connection.isSimulationRunning
                        % Disable UI controls
                        app.disableUIControls();

                        % Update button icon to indicate "Start Simulation"
                        app.BtnSimulate.Icon = 'icons/Start.png';  % start simulation icon
                        app.BtnSimulate.Enable = "on";
                    end
                end
            else
                disp('Please connect to CoppeliaSim before starting the simulation.');
            end
        end

        % Value changed function: SldSpeed
        function SldSpeedValueChanged(app, event)
            newSpeedValue  = app.SldSpeed.Value;
            % Delegate the speed setting to each joint
            for i = 1:length(app.joints)
                app.joints{i}.setVelocity(app.connection.sim, app.connection.clientID, newSpeedValue);
            end
            %Delegate the speed setting to base control
           % base_allSpeed = app.baseControl.allSpeed;
           %app.baseControl.allSpeed = (newSpeedValue * 50.0) / 100;
           app.wheelsSpeed = (newSpeedValue * 5.0) / 100;
        end

        % Value changed function: BtnForward
        function BtnForwardValueChanged(app, event)
            value = app.BtnForward.Value;
            if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.FORWARD,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Value changed function: BtnBackward
        function BtnBackwardValueChanged(app, event)
            value = app.BtnBackward.Value;
             if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.BACKWARD,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Value changed function: BtnLeft
        function BtnLeftValueChanged(app, event)
            value = app.BtnLeft.Value;
             if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.LEFT,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Value changed function: BtnRight
        function BtnRightValueChanged(app, event)
            value = app.BtnRight.Value;
             if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.RIGHT,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Value changed function: BtnCW
        function BtnCWValueChanged(app, event)
            value = app.BtnCW.Value;
             if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.CLOCKWISE,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Value changed function: BtnCCW
        function BtnCCWValueChanged(app, event)
            value = app.BtnCCW.Value;
             if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.COUNTER_CLOCKWISE,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Value changed function: BtnFwdLeft
        function BtnFwdLeftValueChanged(app, event)
            value = app.BtnFwdLeft.Value;
             if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.DIAG_FL,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Value changed function: BtnFwdRight
        function BtnFwdRightValueChanged(app, event)
            value = app.BtnFwdRight.Value;
             if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.DIAG_FR,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Value changed function: BtnBwdLeft
        function BtnBwdLeftValueChanged(app, event)
            value = app.BtnBwdLeft.Value;
             if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.DIAG_BL,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Value changed function: BtnBwdRight
        function BtnBwdRightValueChanged(app, event)
            value = app.BtnBwdRight.Value;
             if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.DIAG_BR,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Button pushed function: BtnCamera
        function BtnCameraButtonPushed(app, event)
            app.visionSensor.captureAndDisplayImage(app.connection.sim, app.connection.clientID, app);
        end

        % Button pushed function: BtnStream
        function BtnStreamButtonPushed(app, event)
            % Toggle streaming state
            app.visionSensor.isStreaming = ~app.visionSensor.isStreaming;

            if app.visionSensor.isStreaming
                app.BtnStream.Text = 'Stop';
                % Start streaming
                while app.visionSensor.isStreaming
                    app.visionSensor.captureAndDisplayImage(app.connection.sim, app.connection.clientID, app);
                    pause(0.1);  % Control the frame rate of the stream
                end
            else
                app.BtnStream.Text = 'Start';
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sensor')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.BtnConnect = uibutton(app.UIFigure, 'push');
            app.BtnConnect.ButtonPushedFcn = createCallbackFcn(app, @BtnConnectButtonPushed, true);
            app.BtnConnect.Icon = fullfile(pathToMLAPP, 'icons', 'Connect.png');
            app.BtnConnect.Text = '';
            app.BtnSimulate = uibutton(app.UIFigure, 'push');
            app.BtnSimulate.ButtonPushedFcn = createCallbackFcn(app, @BtnSimulateButtonPushed, true);
            app.BtnSimulate.Icon = fullfile(pathToMLAPP, 'icons', 'Start.png');
            app.BtnSimulate.Enable = 'off';
            app.BtnSimulate.Text = '';
            app.SPEEDLabel = uilabel(app.UIFigure);
            app.SPEEDLabel.Enable = 'off';
            app.SPEEDLabel.Text = 'SPEED';
            app.SldSpeed = uislider(app.UIFigure);
            app.SldSpeed.ValueChangedFcn = createCallbackFcn(app, @SldSpeedValueChanged, true);
            app.SldSpeed.Enable = 'off';
            app.BtnForward = uibutton(app.UIFigure, 'state');
            app.BtnForward.ValueChangedFcn = createCallbackFcn(app, @BtnForwardValueChanged, true);
            app.BtnForward.Enable = 'off';
            app.BtnForward.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowUp.png');
            app.BtnForward.Text = '';
            app.BtnRight = uibutton(app.UIFigure, 'state');
            app.BtnRight.ValueChangedFcn = createCallbackFcn(app, @BtnRightValueChanged, true);
            app.BtnRight.Enable = 'off';
            app.BtnRight.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowRight.png');
            app.BtnRight.Text = '';
            app.BtnBackward = uibutton(app.UIFigure, 'state');
            app.BtnBackward.ValueChangedFcn = createCallbackFcn(app, @BtnBackwardValueChanged, true);
            app.BtnBackward.Enable = 'off';
            app.BtnBackward.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowDown.png');
            app.BtnBackward.Text = '';
            app.BtnLeft = uibutton(app.UIFigure, 'state');
            app.BtnLeft.ValueChangedFcn = createCallbackFcn(app, @BtnLeftValueChanged, true);
            app.BtnLeft.Enable = 'off';
            app.BtnLeft.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowLeft.png');
            app.BtnLeft.Text = '';
            app.BtnFwdRight = uibutton(app.UIFigure, 'state');
            app.BtnFwdRight.ValueChangedFcn = createCallbackFcn(app, @BtnFwdRightValueChanged, true);
            app.BtnFwdRight.Enable = 'off';
            app.BtnFwdRight.Icon = fullfile(pathToMLAPP, 'icons', 'FwdRight.png');
            app.BtnFwdRight.Text = '';
            app.BtnBwdRight = uibutton(app.UIFigure, 'state');
            app.BtnBwdRight.ValueChangedFcn = createCallbackFcn(app, @BtnBwdRightValueChanged, true);
            app.BtnBwdRight.Enable = 'off';
            app.BtnBwdRight.Icon = fullfile(pathToMLAPP, 'icons', 'BwdRight.png');
            app.BtnBwdRight.Text = '';
            app.BtnFwdLeft = uibutton(app.UIFigure, 'state');
            app.BtnFwdLeft.ValueChangedFcn = createCallbackFcn(app, @BtnFwdLeftValueChanged, true);
            app.BtnFwdLeft.Enable = 'off';
            app.BtnFwdLeft.Icon = fullfile(pathToMLAPP, 'icons', 'FwdLeft.png');
            app.BtnFwdLeft.Text = '';
            app.BtnBwdLeft = uibutton(app.UIFigure, 'state');
            app.BtnBwdLeft.ValueChangedFcn = createCallbackFcn(app, @BtnBwdLeftValueChanged, true);
            app.BtnBwdLeft.Enable = 'off';
            app.BtnBwdLeft.Icon = fullfile(pathToMLAPP, 'icons', 'BwdLeft.png');
            app.BtnBwdLeft.Text = '';
            app.BtnStream = uibutton(app.UIFigure, 'push');
            app.BtnStream.ButtonPushedFcn = createCallbackFcn(app, @BtnStreamButtonPushed, true);
            app.BtnStream.Icon = fullfile(pathToMLAPP, 'icons', 'streaming.png');
            app.BtnStream.Enable = 'off';
            app.BtnStream.Text = '';
            app.BtnCamera = uibutton(app.UIFigure, 'push');
            app.BtnCamera.ButtonPushedFcn = createCallbackFcn(app, @BtnCameraButtonPushed, true);
            app.BtnCamera.Icon = fullfile(pathToMLAPP, 'icons', 'camera.png');
            app.BtnCamera.Enable = 'off';
            app.BtnCamera.Text = '';
            app.BtnCCW = uibutton(app.UIFigure, 'state');
            app.BtnCCW.ValueChangedFcn = createCallbackFcn(app, @BtnCCWValueChanged, true);
            app.BtnCCW.Enable = 'off';
            app.BtnCCW.Icon = fullfile(pathToMLAPP, 'icons', 'CCW.png');
            app.BtnCCW.Text = '';
            app.BtnCW = uibutton(app.UIFigure, 'state');
            app.BtnCW.ValueChangedFcn = createCallbackFcn(app, @BtnCWValueChanged, true);
            app.BtnCW.Enable = 'off';
            app.BtnCW.Icon = fullfile(pathToMLAPP, 'icons', 'CW.png');
            app.BtnCW.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end